classdef Web_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        plotBtn            matlab.ui.control.Button
        uipanel2           matlab.ui.container.Panel
        trucksCheckBox     matlab.ui.control.CheckBox
        carsCheckBox       matlab.ui.control.CheckBox
        uipanel4           matlab.ui.container.Panel
        confIntervalPopup  matlab.ui.control.DropDown
        text1              matlab.ui.control.Label
        uipanel3           matlab.ui.container.Panel
        highwayCheckBox    matlab.ui.control.CheckBox
        cityCheckBox       matlab.ui.control.CheckBox
        uipanel1           matlab.ui.container.Panel
        selectAllYearBtn   matlab.ui.control.Button
        yearListBox        matlab.ui.control.ListBox
        RightPanel         matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        statusText         matlab.ui.control.Label
        axes1              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dataDir;
        selectedYears = [];
        lastData;
        files;
    end
    
    methods (Access = private)
        function updateYearList(app)
            
            % files = dir(fullfile(app.dataDir, '*.xlsx'));
            if isempty(app.files)
                years = {};
            else
                years = cellfun(@(x) extract(x,digitsPattern), app.files, 'UniformOutput', false);
            end
            app.yearListBox.Items = string(years);
            app.yearListBox.Value = years{1};
            
            app.selectedYears = [];
        end
        
        function [cf, gof] = MPG(app, carData, CarTruck, CityHighway, CI)
                % MPG(carData, CARTRUCK, CITYHIGHWAY, CI)
                %
                %   carData      : car data (table object)
                %   CARTRUCK     : 'car' or 'truck' or 'all'
                %   CITYHIGHWAY  : 'city' or 'highway' or 'all'
                %   CI (optional): Confidence Interval (default: 95)
                
                if nargin == 3 % CI not provided
                    CI = 95;
                end
                
                %% Filter out data
                if strcmpi(CarTruck, 'all')
                    idx1 = true(size(carData.Km_gal));
                else
                    idx1 = carData.Car_Truck == CarTruck;
                end
                if strcmpi(CityHighway, 'all')
                    idx2 = true(size(carData.Km_gal));
                else
                    idx2 = carData.City_Highway == CityHighway;
                end
                idx = idx1 & idx2;
                iData = carData.RatedHP(idx);
                dData = carData.Km_gal(idx);
                
                %prepare curve fit data
                [xData, yData] = prepareCurveData( iData, dData );

                % Set up fittype and options.
                ft = fittype( {'1', '1/x'}, 'independent', 'x', 'dependent', 'y', 'coefficients', {'a', 'b'} );
                
                % Fit model to data.
                [cf, gof] = fit( xData, yData, ft );
                                
                
                %% Plot
                scatter(app.axes1,iData,dData,'o','filled');
                app.axes1.NextPlot = 'add';
                
                xx = linspace(min(iData),max(iData),1000);
                plot(app.axes1,xx,cf(xx),'r','LineWidth',2);
                plot(app.axes1,xx,predint(cf,xx,CI*0.01),'r:');
                xlim(app.axes1,[min(iData) max(iData)])
                ylim(app.axes1,[min(dData) max(dData)])
                xlabel(app.axes1,'Potencia en HP')
                ylabel(app.axes1,'Eficiencia en Km/gal')
                set(app.axes1,"XGrid","on","YGrid","on")
                typeCar = "autos";
                switch CarTruck
                    case 'car'
                        typeCar = "autos";
                    case 'truck'
                        typeCar = "camionetas";
                    case 'all'
                        typeCar = " autos y camionetas";
                end
                Road = "ciudad";
                switch CityHighway
                    case 'city'
                        Road = "ciudad";
                    case 'highway'
                        Road = "autopista";
                    case 'all'
                        Road = "ciudad y autopista";
                end

                str = strcat("Eficiencia de combustible para ",typeCar," en ",...
                    Road);
                title(app.axes1,str);
                
                app.axes1.NextPlot = 'replace';
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FuelEconomy_App_OpeningFcn(app)
            [app.files,app.dataDir] = uigetfile("MultiSelect","on");
            updateYearList(app);
            app.figure1.Position = [50 50 900 540]; % g
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.figure1.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {542, 542};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: plotBtn
        function plotBtn_Callback(app, event)
            
            % Error Checking - make sure options are checked
            if (~get(app.carsCheckBox, 'Value') && ~get(app.trucksCheckBox, 'Value')) || ...
                    (~get(app.cityCheckBox, 'Value') && ~get(app.highwayCheckBox, 'Value'))
                errordlg('At least one checkbox from "Car-Truck" and "City-Highway" must be selected');
                return;
            end
            
            set(app.statusText, 'Visible', 'on'); drawnow;
            
            % Get the years selected
            yearIDX = app.yearListBox.Value;
            
            % If the selection changed, reload the data
            if isempty(app.selectedYears) || ~isequal(app.selectedYears, yearIDX)
                
                years = str2double(yearIDX);
                
                % Import data from Excel (or Database)
                data = importData(years, app.dataDir);
                
                data.Car_Truck = categorical(data.Car_Truck);
                data.City_Highway = categorical(data.City_Highway);
                
                app.lastData = data;
                app.selectedYears = yearIDX;
            else
                data = app.lastData;
            end
            
            % Get confidence interval
            CI = str2double(app.confIntervalPopup.Value);
            
            % Get filtering options for Car-Truck
            if get(app.carsCheckBox, 'Value') && get(app.trucksCheckBox, 'Value')
                CarTruck = 'all';
            elseif get(app.carsCheckBox, 'Value')
                CarTruck = 'car';
            else
                CarTruck = 'truck';
            end
            
            % Get filtering options for City-Highway
            if get(app.cityCheckBox, 'Value') && get(app.highwayCheckBox, 'Value')
                CityHighway = 'all';
            elseif get(app.cityCheckBox, 'Value')
                CityHighway = 'city';
            else
                CityHighway = 'highway';
            end
            
            % Perform regression based on filtering options and CI.
            MPG(app,data, CarTruck, CityHighway, CI);
            
            set(app.statusText, 'Visible', 'off'); drawnow;
        end

        % Button pushed function: selectAllYearBtn
        function selectAllYearBtn_Callback(app, event)
            app.yearListBox.Value = app.yearListBox.Items;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Fuel Economy Analyzer';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.figure1);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Title = 'Año';
            app.yearListBox = uilistbox(app.uipanel1);
            app.yearListBox.Items = {''};
            app.yearListBox.Multiselect = 'on';
            app.yearListBox.Value = {''};
            app.selectAllYearBtn = uibutton(app.uipanel1, 'push');
            app.selectAllYearBtn.ButtonPushedFcn = createCallbackFcn(app, @selectAllYearBtn_Callback, true);
            app.selectAllYearBtn.Text = 'Seleccionar todos';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Ciudad o autopista';
            app.cityCheckBox = uicheckbox(app.uipanel3);
            app.cityCheckBox.Text = 'Ciudad';
            app.cityCheckBox.Value = true;
            app.highwayCheckBox = uicheckbox(app.uipanel3);
            app.highwayCheckBox.Text = 'Autopista';
            app.uipanel4 = uipanel(app.GridLayout3);
            app.uipanel4.Title = 'Interv. de confianza';
            app.text1 = uilabel(app.uipanel4);
            app.text1.Text = '%';
            app.confIntervalPopup = uidropdown(app.uipanel4);
            app.confIntervalPopup.Items = {'80', '85', '90', '95', '98', '99'};
            app.confIntervalPopup.Value = '95';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Autos o camionetas';
            app.carsCheckBox = uicheckbox(app.uipanel2);
            app.carsCheckBox.Text = 'Autos';
            app.carsCheckBox.Value = true;
            app.trucksCheckBox = uicheckbox(app.uipanel2);
            app.trucksCheckBox.Text = 'Camionetas';
            app.plotBtn = uibutton(app.GridLayout3, 'push');
            app.plotBtn.ButtonPushedFcn = createCallbackFcn(app, @plotBtn_Callback, true);
            app.plotBtn.Text = 'Graficar';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.axes1 = uiaxes(app.GridLayout2);
            app.statusText = uilabel(app.GridLayout2);
            app.statusText.Visible = 'off';
            app.statusText.Text = 'Procesando';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Web_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @FuelEconomy_App_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end